classdef ProblemChooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ProblemsListBoxLabel  matlab.ui.control.Label
        Problems              matlab.ui.control.ListBox
        Up                    matlab.ui.control.Button
        Down                  matlab.ui.control.Button
        Confirm               matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Up
        function UpPushed(app, event)
            items = app.Problems.Value;
            % for each chosen item, move it up by one. To do this, rotate with one on top;
            % if top one or one immediately above it is selected, do nothing
            names = app.Problems.Items;
            for i = 1:numel(items)
                if items(i) == 1
                    continue;
                elseif i ~= 1 && items(i - 1) == (items(i) - 1)
                    continue;
                else
                    % rotate
                    tmp = names{items(i) - 1};
                    names{items(i) - 1} = names{items(i)};
                    names{items(i)} = tmp;
                end
            end
            app.Problems.Items = names;
            app.Problems.Value = [];
        end

        % Button pushed function: Down
        function DownPushed(app, event)
            items = app.Problems.Value;
            % for each chosen item, move it down by one. To do this, rotate with one on bottom;
            % if bottom one or one immediately below it is selected, do nothing
            names = app.Problems.Items;
            for i = 1:numel(items)
                if items(i) == numel(app.Problems.Items)
                    continue;
                elseif i ~= numel(app.Problems.Items) && items(i + 1) == (items(i) + 1)
                    continue;
                else
                    % rotate
                    tmp = names{items(i) + 1};
                    names{items(i) + 1} = names{items(i)};
                    names{items(i)} = tmp;
                end
            end
            app.Problems.Items = names;
            app.Problems.Value = [];
        end

        % Button pushed function: Confirm
        function ConfirmPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'r';
            app.ProblemsListBoxLabel = uilabel(app.UIFigure);
            app.ProblemsListBoxLabel.Text = 'Problems';
            app.Problems = uilistbox(app.UIFigure);
            app.Problems.Items = {};
            app.Problems.Multiselect = 'on';
            app.Problems.Value = {};
            app.Up = uibutton(app.UIFigure, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Text = 'Move Up';
            app.Down = uibutton(app.UIFigure, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownPushed, true);
            app.Down.Text = 'Move Down';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPushed, true);
            app.Confirm.Text = 'Confirm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProblemChooser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end