classdef ProblemChooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Confirm                        matlab.ui.control.Button
        ProblemOrderPanel              matlab.ui.container.Panel
        OrderLabel                     matlab.ui.control.Label
        Problems                       matlab.ui.control.ListBox
        Up                             matlab.ui.control.Button
        Down                           matlab.ui.control.Button
        AssignmentInformationPanel     matlab.ui.container.Panel
        AssignmentTopicEditFieldLabel  matlab.ui.control.Label
        Topic                          matlab.ui.control.EditField
        PointDistributionPanel         matlab.ui.container.Panel
        Cance                          matlab.ui.control.Button
    end


    properties (Access = public)
        Checks % Description
        Points
        Names
    end

    methods (Static)
    
        function func(check, ~, app, ind)
            app.Points(ind).Visible = check.Value;
            % app.Points(ind).Visible = en;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, problemNames)
            app.Problems.Items = problemNames;
            app.Problems.ItemsData = 1:numel(problemNames);
            y = 230;
            problemNames = problemNames(end:-1:1);
            % for each problem, create check box and corresponding edit field
            for p = numel(problemNames):-1:1
                % Create Name, Check box, Point box
                names(p) = uilabel(app.UIFigure, 'FontName', 'Courier New', ...
                    'Position', [18 y 125 25], 'Text', problemNames{p}, ...
                    'Parent', app.PointDistributionPanel);
                checks(p) = uicheckbox(app.UIFigure, ...
                    'Position', [150 y 100 25], 'Value', false, ...
                    'Text', 'Extra Credit?', 'Parent', app.PointDistributionPanel, ...
                    'ValueChangedFcn', {@ProblemChooser.func, app, numel(problemNames) - p + 1});
                points(p) = uieditfield(app.UIFigure, 'numeric', ...
                    'FontName', 'Courier New', 'Position', [300 y 81 25], ...
                    'Parent', app.PointDistributionPanel, 'Visible', 'off', ...
                    'LowerLimitInclusive', 'on', 'Limits', [0 Inf]);
                y = y - 30;
            end
            names = names(end:-1:1);
            checks = checks(end:-1:1);
            points = points(end:-1:1);
            app.Names = names;
            app.Checks = checks;
            app.Points = points;
            
        end

        % Button pushed function: Up
        function UpPushed(app, event)
            ind = app.Problems.Value;
            names = app.Problems.Items;
            if ind == 1
                return;
            else
                % swap one above with this one
                % names{ind} = names{ind-1}, vv
                tmp = names{ind};
                names{ind} = names{ind-1};
                names{ind-1} = tmp;
                
                tmp = app.Names(ind);
                app.Names(ind) = app.Names(ind-1);
                app.Names(ind-1) = tmp;
                
                tmpPosn = app.Names(ind).Position;
                app.Names(ind).Position = app.Names(ind-1).Position;
                app.Names(ind-1).Position = tmpPosn;
                
                
                tmp = app.Checks(ind);
                app.Checks(ind) = app.Checks(ind-1);
                app.Checks(ind-1) = tmp;
                app.Checks(ind).ValueChangedFcn = {@ProblemChooser.func, app, ind};
                app.Checks(ind-1).ValueChangedFcn = {@ProblemChooser.func, app, ind-1};
                
                tmpPosn = app.Checks(ind).Position;
                app.Checks(ind).Position = app.Checks(ind-1).Position;
                app.Checks(ind-1).Position = tmpPosn;
                
                tmp = app.Points(ind);
                app.Points(ind) = app.Points(ind-1);
                app.Points(ind-1) = tmp;
                
                tmpPosn = app.Points(ind).Position;
                app.Points(ind).Position = app.Points(ind-1).Position;
                app.Points(ind-1).Position = tmpPosn;
            end
            app.Problems.Items = names;
            app.Problems.Value = ind - 1;
        end

        % Button pushed function: Down
        function DownPushed(app, event)
            ind = app.Problems.Value;
            names = app.Problems.Items;
            if ind == numel(names)
                return;
            else
                % swap one above with this one
                % names{ind} = names{ind-1}, vv
                tmp = names{ind};
                names{ind} = names{ind+1};
                names{ind+1} = tmp;
                
                tmp = app.Names(ind);
                app.Names(ind) = app.Names(ind+1);
                app.Names(ind+1) = tmp;
                
                tmpPosn = app.Names(ind).Position;
                app.Names(ind).Position = app.Names(ind+1).Position;
                app.Names(ind+1).Position = tmpPosn;
                
                
                tmp = app.Checks(ind);
                app.Checks(ind) = app.Checks(ind+1);
                app.Checks(ind+1) = tmp;
                
                tmpPosn = app.Checks(ind).Position;
                app.Checks(ind).Position = app.Checks(ind+1).Position;
                app.Checks(ind+1).Position = tmpPosn;
                app.Checks(ind).ValueChangedFcn = {@ProblemChooser.func, app, ind};
                app.Checks(ind+1).ValueChangedFcn = {@ProblemChooser.func, app, ind+1};
                
                tmp = app.Points(ind);
                app.Points(ind) = app.Points(ind+1);
                app.Points(ind+1) = tmp;
                
                tmpPosn = app.Points(ind).Position;
                app.Points(ind).Position = app.Points(ind+1).Position;
                app.Points(ind+1).Position = tmpPosn;
            end
            app.Problems.Items = names;
            app.Problems.Value = ind + 1;
        end

        % Button pushed function: Confirm
        function ConfirmPushed(app, event)
            if isempty(app.Topic.Value)
                app.Topic.BackgroundColor = [.95 .75 .75];
                return;
            end
            uiresume(app.UIFigure);
        end

        % Button pushed function: Cance
        function CanceButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Problem Chooser';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPushed, true);
            app.Confirm.Text = 'Confirm';
            app.ProblemOrderPanel = uipanel(app.UIFigure);
            app.ProblemOrderPanel.Title = 'Problem Order';
            app.OrderLabel = uilabel(app.ProblemOrderPanel);
            app.OrderLabel.Text = 'Order';
            app.Problems = uilistbox(app.ProblemOrderPanel);
            app.Problems.Items = {};
            app.Problems.Value = {};
            app.Up = uibutton(app.ProblemOrderPanel, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Text = 'Move Up';
            app.Down = uibutton(app.ProblemOrderPanel, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownPushed, true);
            app.Down.Text = 'Move Down';
            app.AssignmentInformationPanel = uipanel(app.UIFigure);
            app.AssignmentInformationPanel.Title = 'Assignment Information';
            app.AssignmentTopicEditFieldLabel = uilabel(app.AssignmentInformationPanel);
            app.AssignmentTopicEditFieldLabel.Text = 'Assignment Topic';
            app.Topic = uieditfield(app.AssignmentInformationPanel, 'text');
            app.PointDistributionPanel = uipanel(app.UIFigure);
            app.PointDistributionPanel.Title = 'Point Distribution';
            app.Cance = uibutton(app.UIFigure, 'push');
            app.Cance.ButtonPushedFcn = createCallbackFcn(app, @CanceButtonPushed, true);
            app.Cance.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProblemChooser(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end