classdef TestCaseCompiler_Layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HelpMenu                     matlab.ui.container.Menu
        HelpMenu_2                   matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        RubricTabGroup               matlab.ui.container.TabGroup
        SubTypeTab                   matlab.ui.container.Tab
        SupportingFilesPanel         matlab.ui.container.Panel
        SupportingFilesAddButton     matlab.ui.control.Button
        SupportingFilesRemoveButton  matlab.ui.control.Button
        SupportingFilesListBox       matlab.ui.control.ListBox
        ValuesTabGroup               matlab.ui.container.TabGroup
        TestCase1Tab                 matlab.ui.container.Tab
        OutputBaseWordsPanel         matlab.ui.container.Panel
        OutputBaseWordsEditField     matlab.ui.control.EditField
        RemoveTestCaseButton         matlab.ui.control.Button
        AddTestCaseButton            matlab.ui.control.Button
        ProblemSettingsPanel         matlab.ui.container.Panel
        RecursiveCheckBox            matlab.ui.control.CheckBox
        BannedFunctionsLabel         matlab.ui.control.Label
        BannedFunctionsListBox       matlab.ui.control.ListBox
        BannedFunctionsAddButton     matlab.ui.control.Button
        BannedFunctionsRemoveButton  matlab.ui.control.Button
        BannedFunctionsEditField     matlab.ui.control.EditField
        FunctionBrowsePanel          matlab.ui.container.Panel
        FunctionBrowseButton         matlab.ui.control.Button
        FunctionDriveBrowseButton    matlab.ui.control.Button
        CompileButton                matlab.ui.control.Button
        SaveLocationPanel            matlab.ui.container.Panel
        LocalBrowseButton            matlab.ui.control.Button
        OutputFolderBrowseButton     matlab.ui.control.Button
        LocalOutputLabel             matlab.ui.control.Label
        LocalOutputEditField         matlab.ui.control.EditField
        GoogleDriveLabel             matlab.ui.control.Label
        GoogleDriveEditField         matlab.ui.control.EditField
        DriveOutputClearButton       matlab.ui.control.Button
        LocalOutputClearButton       matlab.ui.control.Button
    end

    
    properties (Access = public, Constant)
        ERROR_ICON = '⚠️ ';
        COMPILE_ICON = '☑ ';
        FOLDER_ICON = '📂 ';
        MISC_ICON = char([55356, 56689, 65039]);
    end
    
    methods (Access = public)
        
        %% getLayout Gets certain fields from every element of the app
        %
        % This is used to get around the shittiness of mlapps. In TestCaseCompiler.m, this 
        % function is called in createComponents() to get the layouts of things from this app.
        % That way, AppDesigner's graphical interface can still be used to move stuff around,
        % but the controller code is kept in the .m file.
        function results = getLayout(app, whichFields)
            results = struct();
            children = properties(app);
%             fields = fieldnames(children);
            fields = children;
            for ind = 1:length(fields)
                child = app.(fields{ind});
                for j = 1:length(whichFields)
                    if isprop(child, whichFields{j})
                        results.(fields{ind}).(whichFields{j}) = child.(whichFields{j});
                    end
                end
%                 if isprop(child, 'Position')
%                     results.(fields{ind}) = child.Position;
%                 end
%                 results.(fields{ind}) = child;
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','MATLAB:subscripting:noSubscriptsSpecified')
            app.UIFigure.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'CS1371 Test Case Compiler';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.RubricTabGroup = uitabgroup(app.UIFigure);
            app.SubTypeTab = uitab(app.RubricTabGroup);
            app.SubTypeTab.Title = '⚠️ Student';
            app.SupportingFilesPanel = uipanel(app.SubTypeTab);
            app.SupportingFilesPanel.Title = '🗃️ Supporting Files';
            app.SupportingFilesAddButton = uibutton(app.SupportingFilesPanel, 'push');
            app.SupportingFilesAddButton.Text = 'Add...';
            app.SupportingFilesRemoveButton = uibutton(app.SupportingFilesPanel, 'push');
            app.SupportingFilesRemoveButton.Text = 'Remove';
            app.SupportingFilesListBox = uilistbox(app.SupportingFilesPanel);
            app.SupportingFilesListBox.Items = {};
            app.SupportingFilesListBox.Value = {};
            app.ValuesTabGroup = uitabgroup(app.SubTypeTab);
            app.TestCase1Tab = uitab(app.ValuesTabGroup);
            app.TestCase1Tab.Title = 'Test Case 1';
            app.OutputBaseWordsPanel = uipanel(app.SubTypeTab);
            app.OutputBaseWordsPanel.Title = '🚩 Output Base Words';
            app.OutputBaseWordsEditField = uieditfield(app.OutputBaseWordsPanel, 'text');
            app.RemoveTestCaseButton = uibutton(app.SubTypeTab, 'push');
            app.RemoveTestCaseButton.Enable = 'off';
            app.RemoveTestCaseButton.Text = '-';
            app.AddTestCaseButton = uibutton(app.SubTypeTab, 'push');
            app.AddTestCaseButton.Text = '+';
            app.ProblemSettingsPanel = uipanel(app.UIFigure);
            app.ProblemSettingsPanel.Title = '🔧 Problem Settings';
            app.RecursiveCheckBox = uicheckbox(app.ProblemSettingsPanel);
            app.RecursiveCheckBox.Enable = 'off';
            app.RecursiveCheckBox.Text = '↻ Recursive?';
            app.BannedFunctionsLabel = uilabel(app.ProblemSettingsPanel);
            app.BannedFunctionsLabel.Enable = 'off';
            app.BannedFunctionsLabel.Text = '🚫 Banned Functions';
            app.BannedFunctionsListBox = uilistbox(app.ProblemSettingsPanel);
            app.BannedFunctionsListBox.Items = {};
            app.BannedFunctionsListBox.Enable = 'off';
            app.BannedFunctionsListBox.Value = {};
            app.BannedFunctionsAddButton = uibutton(app.ProblemSettingsPanel, 'push');
            app.BannedFunctionsAddButton.Enable = 'off';
            app.BannedFunctionsAddButton.Text = 'Add';
            app.BannedFunctionsRemoveButton = uibutton(app.ProblemSettingsPanel, 'push');
            app.BannedFunctionsRemoveButton.Enable = 'off';
            app.BannedFunctionsRemoveButton.Text = 'Remove';
            app.BannedFunctionsEditField = uieditfield(app.ProblemSettingsPanel, 'text');
            app.BannedFunctionsEditField.Enable = 'off';
            app.FunctionBrowsePanel = uipanel(app.UIFigure);
            app.FunctionBrowsePanel.Title = '💻 Function Browse';
            app.FunctionBrowseButton = uibutton(app.FunctionBrowsePanel, 'push');
            app.FunctionBrowseButton.Text = '📂';
            app.FunctionDriveBrowseButton = uibutton(app.FunctionBrowsePanel, 'push');
            app.FunctionDriveBrowseButton.Text = {'🌐'; ''};
            app.CompileButton = uibutton(app.UIFigure, 'push');
            app.CompileButton.Text = '☑ Compile!';
            app.SaveLocationPanel = uipanel(app.UIFigure);
            app.SaveLocationPanel.Title = '💾 Save Location';
            app.LocalBrowseButton = uibutton(app.SaveLocationPanel, 'push');
            app.LocalBrowseButton.Text = '📂';
            app.OutputFolderBrowseButton = uibutton(app.SaveLocationPanel, 'push');
            app.OutputFolderBrowseButton.Text = {'🌐'; ''};
            app.LocalOutputLabel = uilabel(app.UIFigure);
            app.LocalOutputLabel.Text = '📂 Local Output';
            app.LocalOutputEditField = uieditfield(app.UIFigure, 'text');
            app.LocalOutputEditField.Editable = 'off';
            app.LocalOutputEditField.Value = 'Not selected';
            app.GoogleDriveLabel = uilabel(app.UIFigure);
            app.GoogleDriveLabel.Text = '🌐 Google Drive';
            app.GoogleDriveEditField = uieditfield(app.UIFigure, 'text');
            app.GoogleDriveEditField.Editable = 'off';
            app.GoogleDriveEditField.Value = 'Not selected';
            app.DriveOutputClearButton = uibutton(app.UIFigure, 'push');
            app.DriveOutputClearButton.Text = '❌';
            app.LocalOutputClearButton = uibutton(app.UIFigure, 'push');
            app.LocalOutputClearButton.Text = '❌';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestCaseCompiler_Layout

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end